classdef SaveDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ButtonGroup       matlab.ui.container.ButtonGroup
        OriAudioData      matlab.ui.control.RadioButton
        ProcessAudioData  matlab.ui.control.RadioButton
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        PathSelect        matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % 主App
        SaveType % Description
        Path % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, file_path, save_type)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % 获取前一次保存路径
            app.Path = file_path;
            sel_button=get(app.ButtonGroup, 'SelectedObject');
            app.SaveType=get(sel_button,'tag');
        end

        % Button pushed function: PathSelect
        function PathSelectButtonPushed(app, event)
            if (strcmp(app.SaveType,'Process'))
                default_name = 'ProcessAudio.wav';
            else
                default_name = 'RecordAudio.wav';
            end
            [filename, pathname, FileIndex] = uiputfile(app.Path,'文件保存至',default_name);
            if FileIndex == 0  % 如果选择了‘cancel’
 	            return;
            else
	            file_path = [pathname filename];
                % Call main app's public function
                SaveAudioData(app.CallingApp,file_path,app.SaveType);
                app.CallingApp.Save.Enable = 'on';
                % Delete the dialog box  
                delete(app)  
            end    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            app.CallingApp.Save.Enable = 'on';
            delete(app)
        end

        % Callback function
        function ConfigureButtonPushed(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.SaveType = selectedButton.Tag;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.OriAudioData = uiradiobutton(app.ButtonGroup);
            app.OriAudioData.Tag = 'Ori';
            app.OriAudioData.Text = '原始音频';
            app.OriAudioData.Value = true;
            app.ProcessAudioData = uiradiobutton(app.ButtonGroup);
            app.ProcessAudioData.Tag = 'Process';
            app.ProcessAudioData.Text = '处理后音频';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择要保存的音频类型';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选择保存文件路径';
            app.PathSelect = uibutton(app.UIFigure, 'push');
            app.PathSelect.ButtonPushedFcn = createCallbackFcn(app, @PathSelectButtonPushed, true);
            app.PathSelect.Icon = '文件.svg';
            app.PathSelect.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end